classdef GUIAPP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FramenumberEditField            matlab.ui.control.NumericEditField
        FramenumberEditFieldLabel       matlab.ui.control.Label
        RecordFULLSIMAGEButton          matlab.ui.control.Button
        STOPZCheckBox                   matlab.ui.control.CheckBox
        RecordangleLSButton             matlab.ui.control.Button
        ProgressEditField               matlab.ui.control.EditField
        ProgressEditFieldLabel          matlab.ui.control.Label
        SlideButton                     matlab.ui.control.Button
        BrightfieldLampONButton         matlab.ui.control.StateButton
        TabGroup                        matlab.ui.container.TabGroup
        CalibrateLightsheetScanningTab  matlab.ui.container.Tab
        P3OffsetVEditField              matlab.ui.control.NumericEditField
        P3OffsetVEditFieldLabel         matlab.ui.control.Label
        P2OffsetVEditField              matlab.ui.control.NumericEditField
        P2OffsetVEditFieldLabel         matlab.ui.control.Label
        V22nmLabel                      matlab.ui.control.Label
        finetuningofdistancebetweenilluphasesLabel  matlab.ui.control.Label
        finetuningofgloballightsheetoffsetLabel  matlab.ui.control.Label
        LSPhaseYnmEditField             matlab.ui.control.NumericEditField
        LSPhaseYnmEditFieldLabel        matlab.ui.control.Label
        LSGlobalYnmEditField            matlab.ui.control.NumericEditField
        LSGlobalYnmEditFieldLabel       matlab.ui.control.Label
        LSCorrOffsetYEditField          matlab.ui.control.NumericEditField
        LSCorrOffsetYEditFieldLabel     matlab.ui.control.Label
        CalibratedFOVmEditField         matlab.ui.control.NumericEditField
        CalibratedFOVmEditFieldLabel    matlab.ui.control.Label
        ResetFOVFactorButton            matlab.ui.control.Button
        SetFOVFactorButton              matlab.ui.control.Button
        CalculateButton                 matlab.ui.control.Button
        FOVFactorEditField              matlab.ui.control.NumericEditField
        FOVFactorEditFieldLabel         matlab.ui.control.Label
        EnterBotLSPosEditField          matlab.ui.control.NumericEditField
        EnterBotLSPosEditFieldLabel     matlab.ui.control.Label
        EnterTopLSPosEditField          matlab.ui.control.NumericEditField
        EnterTopLSPosEditFieldLabel     matlab.ui.control.Label
        LoadLastLSScanPosButton         matlab.ui.control.Button
        LoadLSScanPosButton             matlab.ui.control.Button
        MoveStageTab                    matlab.ui.container.Tab
        CalculatedFramesEditField       matlab.ui.control.NumericEditField
        CalculatedFramesEditFieldLabel  matlab.ui.control.Label
        ZDistancemEditField             matlab.ui.control.NumericEditField
        ZDistancemEditFieldLabel        matlab.ui.control.Label
        ZStackCalculatorLabel           matlab.ui.control.Label
        BottomZEditField                matlab.ui.control.NumericEditField
        BottomZEditFieldLabel           matlab.ui.control.Label
        TopZEditField                   matlab.ui.control.NumericEditField
        TopZEditFieldLabel              matlab.ui.control.Label
        SetTopZButton                   matlab.ui.control.Button
        SetBottomZButton                matlab.ui.control.Button
        StepDropDown_2Label_3           matlab.ui.control.Label
        StepDropDown_2Label_2           matlab.ui.control.Label
        Button_8                        matlab.ui.control.Button
        Button_7                        matlab.ui.control.Button
        StepDropDown_2                  matlab.ui.control.DropDown
        StepDropDown_2Label             matlab.ui.control.Label
        Button_4                        matlab.ui.control.Button
        Button_3                        matlab.ui.control.Button
        RotationStageCalibTab           matlab.ui.container.Tab
        ActPosEditField                 matlab.ui.control.NumericEditField
        ActPosEditFieldLabel            matlab.ui.control.Label
        GetDeviceDataButton             matlab.ui.control.Button
        Motor2BWFreqkHzEditField        matlab.ui.control.NumericEditField
        Motor2BWFreqkHzEditFieldLabel   matlab.ui.control.Label
        Motor2FWFreqkHzEditField        matlab.ui.control.NumericEditField
        Motor2FWFreqkHzEditFieldLabel   matlab.ui.control.Label
        Motor1BWFreqkHzEditField        matlab.ui.control.NumericEditField
        Motor1BWFreqkHzEditFieldLabel   matlab.ui.control.Label
        Motor1FWFreqkHzEditField        matlab.ui.control.NumericEditField
        Motor1FWFreqkHzEditFieldLabel   matlab.ui.control.Label
        FindMotorFreq2Button            matlab.ui.control.Button
        FindMotorFreq1Button            matlab.ui.control.Button
        HomeELLOButton                  matlab.ui.control.Button
        JogForwardButton                matlab.ui.control.Button
        JogBackwardButton               matlab.ui.control.Button
        GetButton_2                     matlab.ui.control.Button
        SetButton                       matlab.ui.control.Button
        GetButton                       matlab.ui.control.Button
        JogStepEditField                matlab.ui.control.NumericEditField
        JogStepEditFieldLabel           matlab.ui.control.Label
        HomeOffsetEditField             matlab.ui.control.NumericEditField
        HomeOffsetEditFieldLabel        matlab.ui.control.Label
        CalcCorrOffsetYEditField        matlab.ui.control.NumericEditField
        CalcCorrOffsetYEditFieldLabel   matlab.ui.control.Label
        dirCheckBox                     matlab.ui.control.CheckBox
        CenterPosButton                 matlab.ui.control.Button
        includingflybacktimesLabel      matlab.ui.control.Label
        PhaseOffsetnmEditField          matlab.ui.control.NumericEditField
        PhaseOffsetnmEditFieldLabel     matlab.ui.control.Label
        TotalAcqTimeEditField           matlab.ui.control.NumericEditField
        TotalAcqTimeEditFieldLabel      matlab.ui.control.Label
        LineTimensEditField             matlab.ui.control.NumericEditField
        LSExposureTimeLabel             matlab.ui.control.Label
        ExposurebandwidthEditField      matlab.ui.control.NumericEditField
        ExposurebandwidthEditFieldLabel  matlab.ui.control.Label
        LightsheetModeCheckBox          matlab.ui.control.CheckBox
        RecordLSSIMageButton            matlab.ui.control.Button
        RecordSIMageButton              matlab.ui.control.Button
        RecordZStackButton              matlab.ui.control.Button
        RecordImageButton               matlab.ui.control.Button
        LiveButton                      matlab.ui.control.StateButton
        LoadparamsButton                matlab.ui.control.Button
        Angle3Button                    matlab.ui.control.StateButton
        Angle2Button                    matlab.ui.control.StateButton
        Angle1Button                    matlab.ui.control.StateButton
        PhasesDropDown                  matlab.ui.control.DropDown
        PhasesDropDownLabel             matlab.ui.control.Label
        FramesEditField                 matlab.ui.control.NumericEditField
        FramesEditFieldLabel            matlab.ui.control.Label
        SIMLabel                        matlab.ui.control.Label
        ZStepmEditField                 matlab.ui.control.NumericEditField
        ZStepmEditFieldLabel            matlab.ui.control.Label
        HomeButton                      matlab.ui.control.Button
        MinusButton                     matlab.ui.control.Button
        PlusButton                      matlab.ui.control.Button
        StepDropDown                    matlab.ui.control.DropDown
        StepDropDownLabel               matlab.ui.control.Label
        ZStacksLabel                    matlab.ui.control.Label
        StartScanXEditField             matlab.ui.control.NumericEditField
        StartScanXEditFieldLabel        matlab.ui.control.Label
        MoveStageLabel                  matlab.ui.control.Label
        LoadScanPosButton               matlab.ui.control.Button
        StartScanYEditField_2           matlab.ui.control.NumericEditField
        StartScanYEditField_2Label      matlab.ui.control.Label
        FOVmEditField                   matlab.ui.control.NumericEditField
        FOVmEditFieldLabel              matlab.ui.control.Label
        FOVDropDown                     matlab.ui.control.DropDown
        FOVDropDownLabel                matlab.ui.control.Label
        AcqTimemsEditField              matlab.ui.control.NumericEditField
        AcqTimemsEditFieldLabel         matlab.ui.control.Label
        ScanStepYnmEditField            matlab.ui.control.NumericEditField
        ScanStepYnmEditFieldLabel       matlab.ui.control.Label
        ExposuretimemsEditField         matlab.ui.control.NumericEditField
        ExposuretimemsEditFieldLabel    matlab.ui.control.Label
        ObjectiveDropDown               matlab.ui.control.DropDown
        ObjectiveDropDownLabel          matlab.ui.control.Label
        ImageparametersLabel            matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            clear;
                      
            set(0,'DefaultLineLineWidth',2)
            set(0,'DefaultAxesFontSize',12)

            % Define starting values in GUI
            app.ObjectiveDropDown.Value = '60x';
            app.ExposuretimemsEditField.Value = 10; % [ms]
            app.ScanOffsetXnmEditField.Value = 60; % [nm] 
            app.ScanStepYnmEditField.Value = 1000; % [nm] 
            app.AveragingDropDown.Value = '1x';
            app.FOVDropDown.Value = '2048P';
            app.StepDropDown.Value = '1 µm';
            app.StartScanYEditField_2.Value = 0;
            app.StartScanXEditField.Value = 0;
            app.StartScanOffsetYnmEditField.Value = 0;
            app.Angle1Button.Value = 1;
            
            % Define global variables (start values from GUI)
            setappdata(0, 'Objective', app.ObjectiveDropDown.Value);     
            setappdata(0, 'LineTime', app.ExposuretimemsEditField.Value);   
            setappdata(0, 'ScanOffsetX', app.ScanOffsetXnmEditField.Value);   
            setappdata(0, 'ScanStepY', app.ScanStepYnmEditField.Value);   
            setappdata(0, 'Averaging', app.AveragingDropDown.Value);   
            setappdata(0, 'FOV', app.FOVDropDown.Value);   
            setappdata(0, 'ZStep', app.StepDropDown.Value);  
            setappdata(0, 'StartScanValueX', app.StartScanYEditField_2.Value ) % [V]
            setappdata(0, 'StartScanValueY',app.StartScanXEditField.Value) % [V]

            % Define global variables (not selectable from GUI)
            setappdata(0, 'fScan', 50); % [mm] 
            setappdata(0, 'fTubeScan', 200); % [mm] 
            setappdata(0, 'VoltageDiv', 0.8); % [V/°]
            setappdata(0, 'MaxScanFreq', 2000); 
            setappdata(0,'MaxVolt', 7);% [V]
            setappdata(0, 'fDet', 300); % [mm] 
            setappdata(0, 'DetPixelSize', 6.5); % [µm] 
            setappdata(0, 'SampleRate', 10); %[kHz]
            setappdata(0, 'MinScanTime', 0.5) % [ms]
            setappdata(0, 'StartScanValueX', 0) % [V]
            setappdata(0, 'StartScanValueY', 0) % [V]

            % Define variables needed in this function (for calling Init
            % DAQ)
            LineTime = app.ExposuretimemsEditField.Value;
            SampleRate = getappdata(0, 'SampleRate');
            
            % Init DAQ after startup (comment for Test-Mode) %%%%%%%%%%%%%%%         
            [ch1, ixd1, ch2, ixd2, ch3, dqScanner, dqCamTrigger, dqCamTriggerMonitor, dqLightBulb] = Init_DAQ(LineTime, SampleRate);
            
            setappdata(0, 'dqScanner', dqScanner);
            setappdata(0, 'dqCamTrigger', dqCamTrigger);
            setappdata(0, 'dqLightBulb', dqLightBulb);
            
            splist = serialportlist;
            spfreelist = serialportlist('available');

            if splist == spfreelist
            ELLO = Init_ELLO();
            setappdata(0, 'ELLO', ELLO);
            end
            
            % Errors
            %...
           
           SZ = ThorlabsZZStage(); %Calls class definition ThorlabsZZStage.m in folder @ThorlabsZZStage
           SY = ThorlabsZStage();
           SX = ThorlabsZStage1();
           SX.Identify();
           SY.Identify();
           SZ.Identify();
           
           genCLI = NET.addAssembly('C:\Program Files\Thorlabs\Kinesis\Thorlabs.MotionControl.GenericMotorCLI.dll');
           triggerHandle = genCLI.AssemblyHandle.GetType('Thorlabs.MotionControl.GenericMotorCLI.Settings.KCubeTriggerConfigSettings+TriggerPortMode');
           triggerEnums = triggerHandle.GetEnumValues();

           SZ.deviceNET.MotorDeviceSettings.TriggerConfigSettings.Trigger1Mode = triggerEnums.Get(0); 
           SZ.deviceNET.SetMoveRelativeDistance_DeviceUnit(0); %device units 0 because stage also drives after setting triggerenum to 0. 

           setappdata(0, 'SX', SX);
           setappdata(0, 'SY', SY);
           setappdata(0, 'SZ', SZ);
              
           disp('All done, have fun ;)')

        end

        % Value changed function: ObjectiveDropDown
        function ObjectiveSelect(app, event)
            Objective = app.ObjectiveDropDown.Value;
            setappdata(0, 'Objective', Objective)
        end

        % Value changed function: ExposuretimemsEditField
        function ExposuretimemsEditFieldValueChanged(app, event)
            LineTime = app.ExposuretimemsEditField.Value;
            setappdata(0, 'LineTime', LineTime);
        end

        % Callback function: not associated with a component
        function ScanOffsetXnmEditFieldValueChanged(app, event)
            ScanOffsetX = app.ScanOffsetXnmEditField.Value;
            setappdata(0, 'ScanOffsetX', ScanOffsetX);
        end

        % Value changed function: ScanStepYnmEditField
        function ScanStepYnmEditFieldValueChanged(app, event)
            ScanStepY = app.ScanStepYnmEditField.Value;
            setappdata(0, 'ScanStepY', ScanStepY);

        end

        % Callback function: not associated with a component
        function AveragingDropDownValueChanged(app, event)
            Averaging = app.AveragingDropDown.Value;
            setappdata(0, 'Averaging', Averaging);
        end

        % Value changed function: FOVDropDown
        function FOVDropDownValueChanged(app, event)
            FOV = app.FOVDropDown.Value;
            setappdata(0, 'FOV', FOV);
        end

        % Value changed function: StepDropDown
        function StepDropDownValueChanged(app, event)
            StageStep = app.StepDropDown.Value;
            setappdata(0, 'StageStep', StageStep);
        end

        % Button pushed function: MinusButton
        function MinusButtonPushed(app, event)
            Step = app.StepDropDown.Value;
            
            SZ = getappdata(0, 'SZ');
            
            if strcmp(Step, '1 mm')
            inc = 1;           
            elseif strcmp(Step, '100 µm')
            inc = 0.1;    
            elseif strcmp(Step, '50 µm')
            inc = 0.05;  
            elseif strcmp(Step, '10 µm')
            inc = 0.01;  
            elseif strcmp(Step, '1 µm')
            inc = 0.001;    
            elseif strcmp(Step, '200 nm')
            inc = 0.0002;    
            end
            
            SZ = getappdata(0, 'SZ');
            
            Zpos = SZ.pos
           
            SZ.deviceNET.SetJogStepSize(inc); 

            Fmotordirection = Thorlabs.MotionControl.GenericMotorCLI.MotorDirection.Forward;
           
            timeout_val = 60000;
            SZ.deviceNET.MoveJog(Fmotordirection, timeout_val);
          
            AktPos = SZ.pos
            
            
        end

        % Button pushed function: PlusButton
        function PlusButtonPushed(app, event)
            
            Step = app.StepDropDown.Value;
            
            if strcmp(Step, '1 mm')
            inc = 1;           
            elseif strcmp(Step, '100 µm')
            inc = 0.1;    
            elseif strcmp(Step, '50 µm')
            inc = 0.05;  
            elseif strcmp(Step, '10 µm')
            inc = 0.01;  
            elseif strcmp(Step, '1 µm')
            inc = 0.001;    
            elseif strcmp(Step, '200 nm')
            inc = 0.0002;    
            end
            
            SZ = getappdata(0, 'SZ');
            
             
            Zpos = SZ.pos
           
            SZ.deviceNET.SetJogStepSize(inc); 

            Bmotordirection = Thorlabs.MotionControl.GenericMotorCLI.MotorDirection.Backward;
           
            timeout_val = 60000;
            SZ.deviceNET.MoveJog(Bmotordirection, timeout_val);
        
            AktPos = SZ.pos
            
        end

        % Value changed function: ZStepmEditField
        function ZStepmEditFieldValueChanged(app, event)
            value = app.ZStepmEditField.Value;
            
        end

        % Value changed function: FramesEditField
        function FramesEditFieldValueChanged(app, event)
            value = app.FramesEditField.Value;
            
        end

        % Value changed function: PhasesDropDown
        function PhasesDropDownValueChanged(app, event)
            value = app.PhasesDropDown.Value;
            
        end

        % Button pushed function: LoadparamsButton
        function LoadparamsButtonPushed(app, event)
 
            
            if app.LSCorrOffsetYEditField.Value ~= 0
            setappdata(0, 'ScanCorrOffsetY', app.LSCorrOffsetYEditField.Value)
            else
            setappdata(0, 'ScanCorrOffsetY', 0) 
            end
            setappdata(0,'ScanCorrOffsetX', -1.2) 
            
            NewCalibOffsetY = getappdata(0, 'NewCalibOffsetY');
           
            if ~isempty(NewCalibOffsetY) && any(NewCalibOffsetY) 
            setappdata(0, 'ScanCorrOffsetY', NewCalibOffsetY(1));
            if app.Angle2Button.Value == 1
            setappdata(0, 'ScanCorrOffsetY', NewCalibOffsetY(2));
            elseif app.Angle3Button.Value == 1
            setappdata(0, 'ScanCorrOffsetY', NewCalibOffsetY(3));
            end
            end

            SX = getappdata(0,'SX');
            SY = getappdata(0, 'SY'); 
            SZ = getappdata(0, 'SZ');

    	    %%%%
            % read the .txt file for lightsheet parameters
            % Define the file name
            filename = 'LightsheetParams.txt';

            % Open the file
            fileID = fopen(filename, 'r');

            % Read the header line
            headerLine = fgetl(fileID);
            headers = strsplit(headerLine, ',');

            % Read the data line
            dataLine = fgetl(fileID);
            data = str2double(strsplit(dataLine, ','));

            % Close the file
            fclose(fileID);

            % Assign data to variables
            app.EnterTopLSPosEditField.Value = data(1);
            app.EnterBotLSPosEditField.Value = data(2);
            app.P2OffsetVEditField.Value = data(3);
            app.P3OffsetVEditField.Value = data(4);

            %%%%%    
            
            % Calculate focal length of specific objective
            fNikon = 200;
            Magn = app.ObjectiveDropDown.Value;
            Magn(strfind(Magn, 'x')) = [];
            MagnN = str2double(Magn);
            fObj = fNikon/MagnN;

            %Calculate voltage for FOV
            fScan = getappdata(0, 'fScan');
            VoltageDiv = getappdata(0, 'VoltageDiv');
            FOV = app.FOVDropDown.Value;
            fDet = getappdata(0, 'fDet');
            VoltCorrFactorLS = getappdata(0, 'VoltCorrFactorLS'); %%
            
            % Calculate Pixelsize
            DetPixelSize = getappdata(0,'DetPixelSize'); 
            fDet = getappdata(0, 'fDet');

            % apply Correction factor for lightsheet scanning mode                    
            if isempty(VoltCorrFactorLS) || VoltCorrFactorLS == 0
                LSCorrFactor = 1; %% 
            else
                LSCorrFactor = VoltCorrFactorLS;
            end

            SamplePixelSize = DetPixelSize/fDet*fObj;
            setappdata(0, 'SamplePixelSize', SamplePixelSize);

            if strcmp(FOV, '512P')
            FOVn = 512*SamplePixelSize;
            DetPixel = 512;
            elseif strcmp(FOV, '1024P')
            FOVn = 1024*SamplePixelSize;
            DetPixel = 1024;
            elseif strcmp(FOV, '2048P')    
            FOVn = 2048*SamplePixelSize;
            DetPixel = 2048;
            elseif strcmp(FOV, '256P')
            FOVn = 256*SamplePixelSize;
            DetPixel = 256;
            elseif strcmp(FOV, '128P')
            FOVn = 128*SamplePixelSize;
            DetPixel = 128;    
            end

            setappdata(0, 'DetPixel', DetPixel)
            setappdata(0, 'FOVn', FOVn);

            app.FOVmEditField.Value = FOVn; %%

            clear('FOV');
            fTubeScan = getappdata(0, 'fTubeScan');
            MagnObjTubeScan = fTubeScan/fObj;  %% Magnification of scanning tube lens and objective. LSCorrFactor is 1, unless it is calculated by the additional LS calculation menu..
            MagnObjTubeScan0 = fTubeScan/fObj;
           
            VoltFOV = atan(FOVn*MagnObjTubeScan/1000/fScan)*180/pi*VoltageDiv/2; %divided by 2 because of mechanical -> optical angle
            setappdata(0, 'VoltFOV', VoltFOV); 

            %Calculate amount of Y-Steps
            ScanStepY = app.ScanStepYnmEditField.Value;
            VoltsPerStepY = atan((ScanStepY+app.LSPhaseYnmEditField.Value)/1000*MagnObjTubeScan/1000/fScan)*180/pi*VoltageDiv/2;
            VoltsPerOffsetY = atan(-((ScanStepY/str2double(app.PhasesDropDown.Value)))/1000*MagnObjTubeScan0/1000/fScan)*180/pi*VoltageDiv/2; %negative, so first line is in the upper area of the sensor
            StepsY = round(FOVn/ScanStepY*1000); 
            %clear('FOVn');
            setappdata(0, 'StepsY', StepsY);
            setappdata(0, 'VoltsPerStepY', VoltsPerStepY);
            setappdata(0, 'VoltsPerOffsetY', VoltsPerOffsetY);
           
            % Calculate sample rate of DAQ card for scanning
            SampleRate = getappdata(0, 'SampleRate');
            LineTime = getappdata(0, 'LineTime');
            SamplesPerSpot = LineTime*SampleRate;
            setappdata(0, 'SamplesPerSpot', SamplesPerSpot)

            % Calculate acquistion time for a single frame
            AcqTime = (LineTime)*(StepsY+1); %Change the last value
            app.AcqTimemsEditField.Value = AcqTime;  %This field shows the exposure time that needs to be set in micromanager. (explanation p. 31 labbook)
            setappdata(0, 'AcqTime', AcqTime)
            MinScanTime = 1; % ScanTime in ms
            FlyBackSteps = 19; %Amount of Flyback Steps (real amount will be FlybackSteps -1) %default was 19 if only 1 direction, 800 for rotation mount
            FlyBackDuration = MinScanTime*(FlyBackSteps-1);
            setappdata(0, 'FlyBackDuration', FlyBackDuration) %Time of a flyback cycle in ms
            app.TotalAcqTimeEditField.Value = AcqTime+MinScanTime*(FlyBackSteps-1);  % shows the overall acquistion time, including galvo scanner flyback times..

%% Live and rotation SIM mode
            % Calculate Ramp for single imaging with 0 degrees (Y)        
            
            CorrOffsetY = getappdata(0, 'ScanCorrOffsetY');  %this is the phase correction for the inidivudal rotation angles
            CorrOffsetX = getappdata(0, 'ScanCorrOffsetX');

            %if app.Angle1Button.Value == 1
            for j=1:str2double(app.PhasesDropDown.Value)
            CurrentVoltYStart(j) = -VoltFOV/2 + CorrOffsetY + VoltsPerOffsetY*(j-1);     %CorrOffsetY = center line on center of CCD sensor. %VoltsPerOffsetY: Describes Phases        
            for i=1:StepsY+1
            CurrentVoltY = CurrentVoltYStart(j) + (i-1)* VoltsPerStepY*LSCorrFactor; 
            VoltArrayYL(i,j) = CurrentVoltY; %#ok<AGROW> 
            end
            VoltFlyBackStart = CurrentVoltY;   %Start of FlyBackArray
            VoltFlyBackEnd = CurrentVoltYStart(j); % End of FlyBackArray
            FlyBackStep = (VoltFlyBackStart-VoltFlyBackEnd)/FlyBackSteps;
            for k=1:FlyBackSteps-1
            VoltFlyBack(k, j) = VoltFlyBackStart-(FlyBackStep*k);
            end 
            end

            ScanningArrayY = cat(1, repelem(VoltArrayYL, SamplesPerSpot, 1), repelem(VoltFlyBack, SampleRate, 1));   % SampleRate = 10; VoltFlyBackArray has 10 Samples per Spot --> 1 Sample = 0.1 ms, 10 Samples --> 1 ms      
            VoltArrayX = zeros(length(ScanningArrayY), str2double(app.PhasesDropDown.Value));
            VoltArrayX = VoltArrayX + CorrOffsetX; 
            
            % This is for live mode, as well as conventional SIM-mode
            % (rotating lines)
           
            LiveVoltArrayY = ScanningArrayY(:,1);
            LiveVoltArray1 = [LiveVoltArrayY VoltArrayX(:,1)]; 

            setappdata(0, 'VoltArrayY1', ScanningArrayY) %Array including flyback (for record SIMage)
            setappdata(0, 'VoltArrayX1', VoltArrayX);
            setappdata(0, 'CurrentVoltYStart', CurrentVoltYStart);
            setappdata(0, 'SVoltArray1', LiveVoltArray1);
            %end

          % If Phases 2 or 3 are selected, shift line in sx-dir instead of
          % y
            %if app.Angle2Button.Value == 1 || app.Angle3Button.Value == 1
            
            VoltArrayY = 0;
            VoltArrayAddOffsetY = 0; %0.4
            VoltArrayAddOffsetX =-0.7;   %-0.6 
            LineSyncOffsetX = 0.08; % Offset parameter for line overlap of the different directions
            for j=1:str2double(app.PhasesDropDown.Value)
            VoltsPerStepX = atan(ScanStepY*2/sqrt(3)/1000*MagnObjTubeScan/1000/fScan)*180/pi*VoltageDiv/2; %2/sqrt3 ist ergibt sich aus 2*tan60 (verschiebung)
            VoltsPerOffsetX = atan(-(ScanStepY/str2double(app.PhasesDropDown.Value))*2/sqrt(3)/1000*MagnObjTubeScan/1000/fScan)*180/pi*VoltageDiv/2; %offset for horizontal scanning (60, 120° phases)
            setappdata(0, 'VoltsPerStepX', VoltsPerStepX)    
            CurrentVoltXStart(j) = -VoltFOV/2 + CorrOffsetX + VoltsPerOffsetX*(j-1) + LineSyncOffsetX;              
            for i=1:StepsY+1
            CurrentVoltX = CurrentVoltXStart(j) + (i-1)* VoltsPerStepX; 
            VoltArrayXL(i,j) = CurrentVoltX; %#ok<AGROW> 
            end
            VoltFlyBackStart = CurrentVoltX;   %Start of FlyBackArray
            VoltFlyBackEnd = CurrentVoltXStart(j); % End of FlyBackArray
            FlyBackStep = (VoltFlyBackStart-VoltFlyBackEnd)/FlyBackSteps;
            for k=1:FlyBackSteps-1
            VoltFlyBack(k, j) = VoltFlyBackStart-(FlyBackStep*k);
            end          
            end
          
            ScanningArrayX = cat(1, repelem(VoltArrayXL, SamplesPerSpot, 1), repelem(VoltFlyBack, SampleRate, 1)) + VoltArrayAddOffsetX; 

            VoltArrayY = zeros(length(ScanningArrayX), str2double(app.PhasesDropDown.Value));
            VoltArrayY = VoltArrayY + CorrOffsetY + VoltArrayAddOffsetY; 
           
            LiveVoltArray2 = [VoltArrayY(:,1) ScanningArrayX(:,1)]; 
            
            setappdata(0, 'VoltArrayY2', VoltArrayY);
            setappdata(0, 'VoltArrayX2', ScanningArrayX);            
            %setappdata(0, 'CurrentVoltXStart', CurrentVoltXStart);
            setappdata(0, 'SVoltArray2', LiveVoltArray2);
            %end

            dqScanner = getappdata(0, 'dqScanner');
            dqScanner.Channels(1, 3).InitialDelay = app.TotalAcqTimeEditField.Value/1000%-0.003; %Trigger on second pulse (compensation for inital delay between scan movement and camera first line exposure
            setappdata(0, 'CamDelay', dqScanner.Channels(1, 3).InitialDelay);

%% Lighsheet scanning mode


            SystemDelay = 0;  %system delay for optimized synchronization between scanner and camera (in ms) (Settings: 300 nm, 512 P)
            ExposureLines = ceil(ScanStepY/1000/SamplePixelSize);
            LSExpTime = AcqTime/DetPixel; % in ms
            
            PhaseOffset = ceil(ScanStepY/3); 
            TwoPhaseOffset = ceil(2*ScanStepY/3);
            DelayLines = ceil(ScanStepY/1000/SamplePixelSize/3);
            dispm = ['ExpLines: ', num2str(ExposureLines), ' LSExpTime: ', num2str(LSExpTime), ' ms. ', 'PhaseOffset: ', num2str(PhaseOffset),' nm ' , 'DelayLines/Step :', num2str(DelayLines), ' TotalExposure: ', num2str(LSExpTime*ExposureLines), ' ms.'];
            disp(dispm);
            clear dispm
            
            %Generate Light sheet array with respective scanner delay
            if app.LightsheetModeCheckBox.Value == 1
    
         
            LSTuningY = atan((app.LSGlobalYnmEditField.Value)/1000*MagnObjTubeScan/1000/fScan)*180/pi*VoltageDiv/2;
            VoltArrayX = 0;
            VoltArrayY = 0;


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            VoltsPerOffsetY = atan((ScanStepY/str2double(app.PhasesDropDown.Value))/1000*MagnObjTubeScan/1000/fScan)*180/pi*VoltageDiv/2; %negative, so first line is in the upper area of the sensor
            StepsY = round(FOVn/ScanStepY*1000);

        
            % loop for creating the ramps, as well as the horizontal
            % offset


            for k=1:str2double(app.PhasesDropDown.Value)

            CurrentVoltYStart(k) = -VoltFOV/2 + CorrOffsetY + VoltsPerOffsetY*(k-1) + LSTuningY;  % Start Offset in Y Dir (Offset changes after 3 / 5 Phases in x were recorded)
            CurrentVoltXStart(k) =  CorrOffsetX + VoltsPerOffsetX*(k-1);  % Start Offset in X Dir (This changes after every image) 

            %calculate additional rest steps for lightsheet sync
            
            %calculate ramps including flyback (dependent on number of phases)    
            for i=1:StepsY+1
            CurrentVoltY = CurrentVoltYStart(k) + (i-1)* VoltsPerStepY*LSCorrFactor; 
            VoltArrayY(i,k) = CurrentVoltY; %#ok<AGROW> 
            end
            VoltFlyBackStart = CurrentVoltY;   %Start of FlyBackArray
            VoltFlyBackEnd = CurrentVoltYStart(k); % End of FlyBackArray
          
            FlyBackStep = (VoltFlyBackStart-VoltFlyBackEnd)/FlyBackSteps;
            for l=1:FlyBackSteps-1
            VoltFlyBack(l, k) = VoltFlyBackStart-(FlyBackStep*l);
            end 
            
            ScanningArrayX(:,k) = repelem(CurrentVoltXStart(k), length(ScanningArrayY(:,1)));
            end

            RestTime = LineTime/str2double(app.PhasesDropDown.Value); % Resttime for second Phase LineTime/3, for third Phase 2*LineTime/3
            %those parameters need to be calculated for synching the scanner movement to the camera readout mode.  
            
  
            %this is a multidimensional array that cointains all ramps (for
            %scanning in y). dimension depends on the number of phases
            %ScanningArrayY = cat(1, VoltArrayY, VoltFlyBack); 
            %ScanningArrayY = cat(1, repelem(VoltArrayY, SamplesPerSpot, 1), repelem(VoltFlyBack, SampleRate, 1)); 

    
            % This if loop connects the delay arrays (determined by
            % AddSamples) with the galvo arrays and generates the final scanning array, that can be loaded onto the DAQ card. 
            if str2double(app.PhasesDropDown.Value) == 3  % 3 Phases
             
            for i=1:3
            AddSamples = (i-1)*round(RestTime/(1/SampleRate)); 
            AddSampleArray = zeros(AddSamples, 1); 
            AddSampleArray(:,i) = VoltArrayY(1,i);
            SampledVoltFlyback = repelem(VoltFlyBack, SampleRate, 1);
            
            SVoltArrayY(:,i) = repelem(VoltArrayY(:,i), SamplesPerSpot, 1);

            ScanningArrayY(:,i) = cat(1, AddSampleArray(:,i), SVoltArrayY(1:length(SVoltArrayY)-AddSamples, i), SampledVoltFlyback(:,i));

            FirstArray(:,:,i) = [ScanningArrayY(:,i), ScanningArrayX(:,1)]; % The first three arrays have constant x-value --> only phase shifts in y 
            SecondArray(:,:,i) = [ScanningArrayY(:,i), ScanningArrayX(:,2)];
            ThirdArray(:,:,i) = [ScanningArrayY(:,i), ScanningArrayX(:,3)];
            ScanArrayY(:,:,i) = cat(1, FirstArray(:,:,i), SecondArray(:,:,i), ThirdArray(:,:,i));
            end
         
            ScanArrayFinal = cat(1, ScanArrayY(:,:,1), ScanArrayY(:,:,2), ScanArrayY(:,:,3)); %this is the final Scan array that contains 9 phases 
            ScanArray1Dir = cat(1, FirstArray(:,:,1),  FirstArray(:,:,1), SecondArray(:,:,2), ThirdArray(:,:,3)); %first array 2x because camera doesnt get first trigger
            ScanArrayZ = cat(1,  FirstArray(:,:,1), SecondArray(:,:,2), ThirdArray(:,:,3)); %ZStack array cant have four array elements because it will jump after 
            ScanArrayLive = FirstArray(:,:,1);
            ScanArrayFull = cat(1, ScanArray1Dir,  FirstArray(:,:,1), SecondArray(:,:,2), ThirdArray(:,:,3), FirstArray(:,:,1), SecondArray(:,:,2), ThirdArray(:,:,3));
            ScanArrayFast = cat(1, FirstArray(:,:,1), SecondArray(:,:,2), ThirdArray(:,:,3)); %this array is needed for fast scanning along one direction (it doesnt have the initial trigger pulse)


            elseif str2double(app.PhasesDropDown.Value) == 5 
                
            % 5 Phases
            for i=1:5

            AddSamples = (i-1)*round(RestTime/(1/SampleRate)); 
            %AddSamples = 0;
            AddSampleArray = zeros(AddSamples, 1); 
            AddSampleArray(:,i) = VoltArrayY(1,i);
            SampledVoltFlyback = repelem(VoltFlyBack, SampleRate, 1);
            
            SVoltArrayY(:,i) = repelem(VoltArrayY(:,i), SamplesPerSpot, 1);

            ScanningArrayY(:,i) = cat(1, AddSampleArray(:,i), SVoltArrayY(1:length(SVoltArrayY)-AddSamples, i), SampledVoltFlyback(:,i));

            FirstArray(:,:,i) = [ScanningArrayY(:,i), ScanningArrayX(:,1)];
            SecondArray(:,:,i) = [ScanningArrayY(:,i), ScanningArrayX(:,2)];
            ThirdArray(:,:,i) = [ScanningArrayY(:,i), ScanningArrayX(:,3)];
            FourthArray(:,:,i) = [ScanningArrayY(:,i), ScanningArrayX(:,4)];
            FifthArray(:,:,i) = [ScanningArrayY(:,i), ScanningArrayX(:,5)];
            ScanArrayY(:,:,i) = cat(1, FirstArray(:,:,i), SecondArray(:,:,i), ThirdArray(:,:,i), FourthArray(:,:,i), FifthArray(:,:,i));
            end
            ScanArrayFinal = cat(1, ScanArrayY(:,:,1), ScanArrayY(:,:,2), ScanArrayY(:,:,3) , ScanArrayY(:,:,4) , ScanArrayY(:,:,5)); % this is the final scan array that cointains 25 phases
            ScanArray1Dir = cat(1, FirstArray(:,:,1), FirstArray(:,:,1), SecondArray(:,:,2), ThirdArray(:,:,3), FourthArray(:,:,4), FifthArray(:,:,5));
            ScanArrayZ = cat(1, FirstArray(:,:,1), SecondArray(:,:,2), ThirdArray(:,:,3), FourthArray(:,:,4), FifthArray(:,:,5));
            ScanArrayLive = FirstArray(:,:,1);
            ScanArrayFull = cat(1, ScanArray1Dir, FirstArray(:,:,1), SecondArray(:,:,2), ThirdArray(:,:,3), FourthArray(:,:,4), FifthArray(:,:,5), FirstArray(:,:,1), SecondArray(:,:,2), ThirdArray(:,:,3), FourthArray(:,:,4), FifthArray(:,:,5));
            ScanArrayFast = cat(1, FirstArray(:,:,1), SecondArray(:,:,2), ThirdArray(:,:,3), FourthArray(:,:,4), FifthArray(:,:,5));
            end 
     
            setappdata(0, 'LSScanArrayFinal', ScanArrayFinal); %writing final scan array to memory         
            setappdata(0, 'LSScanArray1Dir', ScanArray1Dir);
            setappdata(0, 'ScanArrayLive', ScanArrayLive);
            setappdata(0, 'ScanArrayZ', ScanArrayZ);
            setappdata(0, 'ScanArrayFull', ScanArrayFull);
            setappdata(0, 'ScanArrayFast', ScanArrayFast);
           
            end

          
            app.PhaseOffsetnmEditField.Value = PhaseOffset;
            app.LineTimensEditField.Value = LSExpTime*10^6;
            %app.LineTimensEditField.Value = AcqTime/DetPixel*10^6;
            app.ExposurebandwidthEditField.Value = ExposureLines;

            ExposureLineOffset = 0;
            setappdata(0, 'ExposureLineOffset', ExposureLineOffset);
            setappdata(0, 'ExposureLines', ExposureLines);
            
            setappdata(0, 'SystemDelay', SystemDelay) 
            setappdata(0, 'dqScanner', dqScanner)

            
            % calculate pixel offset
            PDet = DetPixel;
           
            xFFT = linspace(-PDet/2, PDet/2, PDet);
            xOTF = zeros(1,PDet);

            for i=1:PDet
            if xFFT(i) < 0
            xOTF(1,i) = -PDet/xFFT(1,i)*SamplePixelSize;
            elseif xFFT(i) > 0
            xOTF(i) = PDet/xFFT(1,i)*SamplePixelSize;    
            elseif xFFT(i) == 0
            xOTF(i) = xOTF(i-1);
            end
            end


        end

        % Value changed function: LiveButton
        function LiveButtonValueChanged(app, event)
           
            LineTime = app.ExposuretimemsEditField.Value;
            FlyBackDuration = getappdata(0, 'FlyBackDuration');
            dqScanner = getappdata(0, 'dqScanner'); %%%%%%%%%%%%%%%%
            MinScanTime = getappdata(0, 'MinScanTime');
           
            % Camera trigger
            TAcqTime = app.TotalAcqTimeEditField.Value;
            dqScanner.Channels(1, 3).Frequency = 1/(TAcqTime/1000); % Frequency of a single image (camera trigger)
            %dqScanner.Channels(1, 3).InitialDelay = 0.001; % Frequency of a single image (camera trigger)
            
            % Error messages
            if LineTime < MinScanTime
            disp('ERROR: Line integration time is too short. Increase line time..')
            else
           
            if app.LiveButton.Value == 1
            
            % Get 1-phase scan arrays for 90° diecton or for 30°, 150° dir
            % (only for live mode)
            if app.Angle1Button.Value == 1  
            SVoltArray = getappdata(0, 'SVoltArray1');
            else
            SVoltArray = getappdata(0, 'SVoltArray2');
            end    

            % Get scan arrays with line delay for lightsheet scanning mode
            if app.LightsheetModeCheckBox.Value == 1
            SVoltArray = getappdata(0, 'ScanArrayLive');    
            end
            
            %SVoltArray = [0.8486 -0.3; 0.5 0]; 

            % increase samples for DAQ card if necessary
            while length(SVoltArray) < 5000
            if length(SVoltArray) < 5000
            SVoltArray = repmat(SVoltArray,2,1);             
            end
            end

            SVoltArray = repmat(SVoltArray,5,1); % reduces preload iterations and therefore increases stability
            
            % load arrays to DAQ card
            preload(dqScanner, SVoltArray);  %%%%%%%%%%%%%%%%%%%
            
            % scanning routine
            start(dqScanner, 'RepeatOutput'); %%%%%%%%%%%%%%%%%%%% 
            disp('Acquire those sexy SIMages')
            elseif app.LiveButton.Value == 0

            stop(dqScanner);      %%%%%%%%%%%%%%%
           
            dqScanner.write([-4,-4])
            flush(dqScanner);
            
            disp('Continue working, noob!')
            end
            end

        end

        % Button pushed function: LoadScanPosButton
        function LoadScanPosButtonPushed(app, event)
        CorrOffsetX = getappdata(0, 'ScanCorrOffsetX');
        CorrOffsetY= getappdata(0, 'ScanCorrOffsetY');
        ScanValueX = app.StartScanYEditField_2.Value;
        ScanValueY = app.StartScanXEditField.Value;

        dqScanner = getappdata(0,'dqScanner');       
        write(dqScanner, [ScanValueX ScanValueY]);    
        dispm = ['Scanning Position X :' num2str(ScanValueX) ' Y: ' num2str(ScanValueY) '. '];

        app.StartScanXEditField.Value = ScanValueY;
        app.StartScanYEditField_2.Value = ScanValueX;

        disp(dispm);

        VoltFOV = getappdata(0, 'VoltFOV');

        app.CalcCorrOffsetYEditField.Value = ScanValueX+VoltFOV/2;

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            SX = getappdata(0, 'SX');
            SY = getappdata(0, 'SY');
            SZ = getappdata(0, 'SZ');
            
            if SZ.isConnected == 1
            SZ.Disconnect();
            end
            if SX.isConnected == 1
            SX.Disconnect();
            end
            if SY.isConnected == 1
            SY.Disconnect();
            end
            daqreset;
            fprintf('Shutting down DAQ connection and zeroing scanners\n')
            delete(app)


        end

        % Button pushed function: HomeButton
        function HomeButtonPushed(app, event)
            
            SX = getappdata(0, 'SX');
            SY = getappdata(0, 'SY');
            SZ = getappdata(0, 'SZ');
            
            SX.Home();
            SY.deviceNET.Home(0);
            SZ.Home();
            
             disp('Homing done.')
            
            
        end

        % Button pushed function: LoadLSScanPosButton
        function LoadLSScanPosButtonPushed(app, event)
         CurrentVoltYStart = getappdata(0, 'CurrentVoltYStart');
         CorrOffsetX = getappdata(0, 'ScanCorrOffsetX');


        dqScanner = getappdata(0,'dqScanner');        
        write(dqScanner, [CurrentVoltYStart(1) CorrOffsetX]);   
        dispm = ['Scanning Position X :' num2str(CorrOffsetX) ' Y: ' num2str(CurrentVoltYStart(1)) '. '];
        disp(dispm);

        app.StartScanXEditField.Value = CorrOffsetX;
        app.StartScanYEditField_2.Value = round(CurrentVoltYStart(1),3);
        end

        % Value changed function: Angle1Button
        function Angle1ButtonValueChanged(app, event)
            value = app.Angle1Button.Value;
            
            if value == 1
            app.Angle2Button.Value = 0;
            app.Angle3Button.Value = 0;
            app.RotationModeButton.Value = 0;
            end    

        end

        % Value changed function: Angle2Button
        function Angle2ButtonValueChanged(app, event)
            value = app.Angle2Button.Value;
            if value == 1
            app.Angle1Button.Value = 0;
            app.Angle3Button.Value = 0;
            app.RotationModeButton.Value = 0;
            end    
        end

        % Button pushed function: RecordSIMageButton
        function RecordSIMageButtonPushed(app, event)


            LineTime = app.ExposuretimemsEditField.Value;
            MinScanTime = getappdata(0, 'MinScanTime');
            dqScanner = getappdata(0, 'dqScanner'); %%%%%%%%%%%%%%%%
            dqCamTrigger = getappdata(0, 'dqCamTrigger');  %%%%%%%%%%%%%
            CorrOffsetX = getappdata(0, 'ScanCorrOffsetX');
            ExposureLineOffset = getappdata(0, 'ExposureLineOffset');
            AcqTime = getappdata(0, 'AcqTime');
            TAcqTime = app.TotalAcqTimeEditField.Value;
            Phases = str2double(app.PhasesDropDown.Value);
       
            dqScanner.Channels(1, 3).Frequency = 1/(TAcqTime/1000); % This is the channel for camera trigger
            %dqScanner.Channels(1, 5).Frequency = 1/(TAcqTime*3/1000);  %This is the channel for rotation mount


            if LineTime < MinScanTime
            disp('ERROR: Line integration time is too short. Increase line time..')
            else
           
            
            SamplesPerSpot = getappdata(0, 'SamplesPerSpot');
            VoltArrayY1 = getappdata(0, 'VoltArrayY1');   % Y-Scanning Array with 3/5 Phases (scan with k-vector = 90°)
            VoltArrayX1 = getappdata(0, 'VoltArrayX1'); % Corresponding X-Scanning Array with constant value
            VoltArrayX2 = getappdata(0, 'VoltArrayX2'); % X-Scanning Array with 3/5 Phases (scan with k-vector = 30°, 150°);
            VoltArrayY2 = getappdata(0, 'VoltArrayY2');   % Corresponding Y-Scanning Array with constant value


            VoltArrayXX = 0;
            VoltArrayYY = 0;

            if Phases == 3
            if app.Angle1Button.Value == 1
            VoltArrayYY = cat(1, VoltArrayY1(:,1), VoltArrayY1(:,1), VoltArrayY1(:,2), VoltArrayY1(:,3));
            VoltArrayXX = cat(1, VoltArrayX1(:,1), VoltArrayX1(:,1), VoltArrayX1(:,2), VoltArrayX1(:,3)); % Repeat X-Array that it has same amount of elements as the Y-Scanning Array.
            elseif app.Angle2Button.Value == 1 || app.Angle3Button.Value == 1
            VoltArrayYY = cat(1, VoltArrayY2(:,1), VoltArrayY2(:,1), VoltArrayY2(:,2), VoltArrayY2(:,3));
            VoltArrayXX = cat(1, VoltArrayX2(:,1), VoltArrayX2(:,1), VoltArrayX2(:,2), VoltArrayX2(:,3)); % Repeat X-Array that it has same amount of elements as the Y-Scanning Array.  
            end
            elseif Phases == 5
            if app.Angle1Button.Value == 1
            VoltArrayYY = cat(1, VoltArrayY1(:,1), VoltArrayY1(:,1), VoltArrayY1(:,2), VoltArrayY1(:,3), VoltArrayY1(:,4), VoltArrayY1(:,5));
            VoltArrayXX =  cat(1, VoltArrayX1(:,1), VoltArrayX1(:,1), VoltArrayX1(:,2), VoltArrayX1(:,3), VoltArrayX1(:,4), VoltArrayX1(:,5));    
            elseif app.Angle2Button.Value == 1 || app.Angle3Button.Value == 1
            VoltArrayYY = cat(1, VoltArrayY2(:,1), VoltArrayY2(:,1), VoltArrayY2(:,2), VoltArrayY2(:,3), VoltArrayY2(:,4), VoltArrayY2(:,5));
            VoltArrayXX =  cat(1, VoltArrayX2(:,1), VoltArrayX2(:,1), VoltArrayX2(:,2), VoltArrayX2(:,3), VoltArrayX2(:,4), VoltArrayX2(:,5)); 
            end
            end
            

            SVoltArray = [VoltArrayYY VoltArrayXX];  
           
            while length(SVoltArray) < 5000  %If Sample count is under 5k, DAQ card gives an error. Array is repeated if element count is too low..
            if length(SVoltArray) < 5000
            SVoltArray = repmat(SVoltArray,2,1);             
            end
            end
            
            preload(dqScanner, SVoltArray);  %%%%%%%%%%%%%%%%%%%
            start(dqScanner); %%%%%%%%%%%%%%%%%%%% 
            
            if app.RotationModeButton.Value == 1
            pause(TAcqTime/1000*(Phases*3+1));    
            else
            pause(TAcqTime/1000*(Phases+1));
            end 

            stop(dqScanner);
            dqScanner.write([-4,-4])     %%%%%%%%%%%%%%%
           
            flush(dqScanner);
                       
            disp('SIMages recorded! :)')
            end
            
        end

        % Button pushed function: RecordLSSIMageButton
        function RecordLSSIMageButtonPushed(app, event)
            
            
          SZ = getappdata(0, 'SZ');
          SZ.pos

          dqScanner = getappdata(0, 'dqScanner'); %%%%%%%%%%%%%%%%
          LineTime = getappdata(0, 'LineTime');
          TAcqTime = app.TotalAcqTimeEditField.Value; 
          dqScanner.Channels(1, 3).Frequency = 1/(TAcqTime/1000);
          SVoltArray = getappdata(0, 'LSScanArrayFinal');
          MinScanTime = getappdata(0, 'MinScanTime');
          LSScanArray1Dir = getappdata(0, 'LSScanArray1Dir');
          WaitScan = TAcqTime/1000*((str2double(app.PhasesDropDown.Value))*3+1);

            if LineTime < MinScanTime
            disp('ERROR: Line integration time is too short. Increase line time..')
            else
          
            if app.dirCheckBox.Value == 1
            SVoltArray = LSScanArray1Dir;
            WaitScan = TAcqTime/1000*(str2double(app.PhasesDropDown.Value)+1);
            end

            while length(SVoltArray) < 5000  %If Sample count is under 5k, DAQ card gives an error. Array is repeated if element count is too low..
            if length(SVoltArray) < 5000
            SVoltArray = repmat(SVoltArray,2,1);             
            end
            end
            
            preload(dqScanner, SVoltArray);  %%%%%%%%%%%%%%%%%%%
            start(dqScanner); %%%%%%%%%%%%%%%%%%%% 
            
            pause(WaitScan); 
            
            stop(dqScanner);

            stop(dqScanner);
       
            dqScanner.write([-4,-4]) 

            flush(dqScanner);

                       
            disp('LS-SIMages recorded! :)')
            end

            SZ.pos

        end

        % Button pushed function: CenterPosButton
        function CenterPosButtonPushed(app, event)
        CorrOffsetX = getappdata(0, 'ScanCorrOffsetX');
        CorrOffsetY= getappdata(0, 'ScanCorrOffsetY');
         VoltFOV = getappdata(0, 'VoltFOV');
        ScanValueX =  CorrOffsetX;
        ScanValueY =  CorrOffsetY;
        

        dqScanner = getappdata(0,'dqScanner');         %%%%%%%%%%%%
        write(dqScanner, [ScanValueY ScanValueX]);    %%%%%%%%%%%%%
        dispm = ['Scanning Position X :' num2str(ScanValueX) ' Y: ' num2str(ScanValueY) '. '];
        disp(dispm);


        app.StartScanXEditField.Value = ScanValueX;
        app.StartScanYEditField_2.Value = ScanValueY;
        end

        % Value changed function: Angle3Button
        function Angle3ButtonValueChanged(app, event)
            value = app.Angle3Button.Value;
            if value == 1
            app.Angle1Button.Value = 0;
            app.Angle2Button.Value = 0;
            end    
        end

        % Button pushed function: LoadLastLSScanPosButton
        function LoadLastLSScanPosButtonPushed(app, event)
         CurrentVoltYStart = getappdata(0, 'CurrentVoltYStart');
         CorrOffsetX = getappdata(0, 'ScanCorrOffsetX');
         VoltFOV = getappdata(0, 'VoltFOV');

         dqScanner = getappdata(0,'dqScanner');         %%%%%%%%%%%%
         write(dqScanner, [CurrentVoltYStart(1)+VoltFOV CorrOffsetX]);    %%%%%%%%%%%%%
         dispm = ['Scanning Position X :' num2str(CorrOffsetX) ' Y: ' num2str(CurrentVoltYStart(1)) '. '];
         disp(dispm);

         app.StartScanXEditField.Value = CorrOffsetX;
         app.StartScanYEditField_2.Value = round(CurrentVoltYStart(1)+VoltFOV ,3);
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            VoltFOV = getappdata(0, 'VoltFOV');
            FOV = getappdata(0, 'FOVn');

            CorrFOV = - app.EnterTopLSPosEditField.Value + app.EnterBotLSPosEditField.Value;

            CorrFactor = round(CorrFOV/VoltFOV,3); 
            app.FOVFactorEditField.Value = CorrFactor;
            app.CalibratedFOVmEditField.Value = CorrFactor*FOV;


            NewCalibOffsetY(1) = +VoltFOV/2+app.EnterTopLSPosEditField.Value;
            app.LSCorrOffsetYEditField.Value = round(NewCalibOffsetY, 4);
            
            NewCalibOffsetY(2) = +VoltFOV/2+app.P2OffsetVEditField.Value;
            NewCalibOffsetY(3) = +VoltFOV/2+app.P3OffsetVEditField.Value;


            setappdata(0, 'NewCalibOffsetY', NewCalibOffsetY);


            tData = table(round(app.EnterTopLSPosEditField.Value,3), round(app.EnterBotLSPosEditField.Value, 3), round(app.P2OffsetVEditField.Value,3),round(app.P3OffsetVEditField.Value,3),'variablenames',{'Angle 1','Angle1 Bot','Angle 2', 'Angle 3'});


            writetable(tData, 'LightsheetParams.txt');


        end

        % Button pushed function: SetFOVFactorButton
        function SetFOVFactorButtonPushed(app, event)
             setappdata(0, 'VoltCorrFactorLS', app.FOVFactorEditField.Value);
             FOV = getappdata(0, 'FOVn');
             app.CalibratedFOVmEditField.Value = app.FOVFactorEditField.Value*FOV;
             disp('FOV Factor set!');
        end

        % Button pushed function: ResetFOVFactorButton
        function ResetFOVFactorButtonPushed(app, event)
            setappdata(0, 'VoltCorrFactorLS', 1);


            NewCalibOffsetY = 0;
            setappdata(0, 'NewCalibOffsetY', NewCalibOffsetY)
        end

        % Button pushed function: RecordZStackButton
        function RecordZStackButtonPushed(app, event)
            
            % For z-stacks, it is very important to manually correct the
            % mechanical slip of the stage. This can be achieved by
            % driving to a point above the desired z-stack start position
            % (plus button) and then moving the stage to a slightly lower position (minus
            % button). If this is not done, the stage will record dead
            % frames, which depend on the actual step size (10 DF at 1 mum
            % step, 35 DF at 200 nm step. For example, drive the stage to a
            % point above the z-stack and press the minus button 10 times
            % before starting the z-stack with 1 mum increment. 

            SZ = getappdata(0, 'SZ');
            CamDelay = getappdata(0, 'CamDelay');
         
            disp(['Start z-pos: ', num2str(SZ.pos)])

            Step = app.ZStepmEditField.Value/1000;
            
            DeviceUnits = Step*34555;

            SZ.deviceNET.SetJogStepSize(Step); 
            
            SZ.deviceNET.SetMoveRelativeDistance_DeviceUnit(DeviceUnits);

            Fmotordirection = Thorlabs.MotionControl.GenericMotorCLI.MotorDirection.Forward;
             
            timeout_val = 60000;

            Frames = app.FramesEditField.Value;

            genCLI = NET.addAssembly('C:\Program Files\Thorlabs\Kinesis\Thorlabs.MotionControl.GenericMotorCLI.dll');
            triggerHandle = genCLI.AssemblyHandle.GetType('Thorlabs.MotionControl.GenericMotorCLI.Settings.KCubeTriggerConfigSettings+TriggerPortMode');
            triggerEnums = triggerHandle.GetEnumValues();

            SZ.deviceNET.MotorDeviceSettings.TriggerConfigSettings.Trigger1Mode = triggerEnums.Get(2);

         
            LineTime = app.ExposuretimemsEditField.Value;
            dqScanner = getappdata(0, 'dqScanner'); %%%%%%%%%%%%%%%%


            ch5 = addoutput(dqScanner, "Dev2", "ctr0", "PulseGeneration");  %PFI12/P2.4 (stage)

            MinScanTime = getappdata(0, 'MinScanTime');
            FlyBackDuration = getappdata(0, 'FlyBackDuration');
            AcqTime = getappdata(0, 'AcqTime');
            
            TAcqTime = app.TotalAcqTimeEditField.Value;

            % Camera trigger
            if app.AverageCheckBox.Value == 0
            StAcqTime = app.TotalAcqTimeEditField.Value;
            elseif app.AverageCheckBox.Value == 1
            StAcqTime = app.TotalAcqTimeEditField.Value*(str2double(app.PhasesDropDown.Value)+1);
            end

            dqScanner.Channels(1, 3).Frequency = 1/(TAcqTime/1000); % Frequency of a single image (camera trigger)
            dqScanner.Channels(1, 4).Frequency = 1/(StAcqTime/1000); % Stage trigger
            dqScanner.Channels(1, 4).InitialDelay = AcqTime/1000 + CamDelay;
           
            % Error messages
            if LineTime < MinScanTime
            disp('ERROR: Line integration time is too short. Increase line time..')
            else
           

            % Get scan arrays with line delay for lightsheet scanning mode
            if app.LightsheetModeCheckBox.Value == 1 && app.AverageCheckBox.Value == 0
            SVoltArray = getappdata(0, 'ScanArrayLive');    
            elseif app.LightsheetModeCheckBox.Value == 1 && app.AverageCheckBox.Value == 1
            SVoltArray = getappdata(0,'LSScanArray1Dir');
            end
            
            % increase samples for DAQ card if necessary
            while length(SVoltArray) < 5000
            if length(SVoltArray) < 5000
            SVoltArray = repmat(SVoltArray,2,1);             
            end
            end
            
            % load arrays to DAQ card
            preload(dqScanner, SVoltArray);  %%%%%%%%%%%%%%%%%%%
            
            % scanning routine
            start(dqScanner, 'RepeatOutput'); %%%%%%%%%%%%%%%%%%%% 

            for i=1:Frames
            pause((StAcqTime)/1000);            
            disp(['Frame ', num2str(i) , ' of ' , num2str(Frames) ,' has been captured. ZPos: ', num2str(SZ.pos)]);
            app.ProgressEditField.Value = ['Frame ', num2str(i) , ' / ' , num2str(Frames)];
            if app.STOPZCheckBox.Value == 1
            disp('Z-Stack interrupted')
            break
     
            end
            
            end
        
            stop(dqScanner);      %%%%%%%%%%%%%%%
           
            dqScanner.write([-4,-4])

            disp(['Total time elapsed: xxx  Final z-pos: ', num2str(SZ.pos)]);


            removechannel(dqScanner, 4);
            flush(dqScanner);
              
            
            end
   
            SZ.deviceNET.MotorDeviceSettings.TriggerConfigSettings.Trigger1Mode = triggerEnums.Get(0);

            SZ.deviceNET.SetMoveRelativeDistance_DeviceUnit(0); %device units 0 because stage also drives after setting triggerenum to 0.
            
        end

        % Callback function: not associated with a component
        function RotationModeButtonValueChanged(app, event)
            value = app.RotationModeButton.Value;
            

            app.Angle1Button.Value = 0;
            app.Angle2Button.Value = 0;
            app.Angle3Button.Value = 0;

        end

        % Value changed function: BrightfieldLampONButton
        function BrightfieldLampONButtonValueChanged(app, event)
            value = app.BrightfieldLampONButton.Value;
            dqLightBulb = getappdata(0, 'dqLightBulb');
            dqLightBulb.Channels.Frequency = 0.2;

            if value == 1
            start(dqLightBulb, 'Continuous')
            elseif value == 0
            stop(dqLightBulb)
            end

        end

        % Button pushed function: SlideButton
        function SlideButtonPushed(app, event)
          SX = getappdata(0, 'SX'); 
          SY = getappdata(0, 'SY');
          SZ = getappdata(0, 'SZ');


          SX.Move_No_Wait(39.73);
          SY.Move_No_Wait(17.16);
          SZ.Move_No_Wait(9.32);
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            

            % x > button 
            Step = app.StepDropDown_2.Value;
            
            SX = getappdata(0,'SX');
           
              
            if strcmp(Step, '1 mm')
            inc = 1;  
            elseif strcmp(Step, '500 µm')
            inc = 0.5;      
            elseif strcmp(Step, '200 µm')
            inc = 0.2;           
            elseif strcmp(Step, '100 µm')
            inc = 0.1;    
            elseif strcmp(Step, '50 µm')
            inc = 0.05;    
            elseif strcmp(Step, '30 µm')
            inc = 0.03;    
            elseif strcmp(Step, '10 µm')
            inc = 0.01;   
            elseif strcmp(Step, '1 µm')
            inc = 0.001;    
            end
            
               
            SX.deviceNET.SetJogStepSize(inc); 

            Fmotordirection = Thorlabs.MotionControl.GenericMotorCLI.MotorDirection.Forward;
           
           timeout_val = 60000;
           SX.deviceNET.MoveJog(Fmotordirection, timeout_val);
         
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            % x < button

             Step = app.StepDropDown_2.Value;
            
            SX = getappdata(0,'SX');
           
            if strcmp(Step, '1 mm')
            inc = 1;  
            elseif strcmp(Step, '500 µm')
            inc = 0.5;      
            elseif strcmp(Step, '200 µm')
            inc = 0.2;           
            elseif strcmp(Step, '100 µm')
            inc = 0.1;    
            elseif strcmp(Step, '50 µm')
            inc = 0.05;    
            elseif strcmp(Step, '30 µm')
            inc = 0.03;    
            elseif strcmp(Step, '10 µm')
            inc = 0.01;   
            elseif strcmp(Step, '1 µm')
            inc = 0.001;    
            end
            
               
            SX.deviceNET.SetJogStepSize(inc); 

            Bmotordirection = Thorlabs.MotionControl.GenericMotorCLI.MotorDirection.Backward;
           
           timeout_val = 60000;
           SX.deviceNET.MoveJog(Bmotordirection, timeout_val);
        end

        % Button pushed function: Button_8
        function Button_5Pushed(app, event)
       
            Step = app.StepDropDown_2.Value;
            
            SY = getappdata(0,'SY');
           
              
            if strcmp(Step, '1 mm')
            inc = 1;  
            elseif strcmp(Step, '500 µm')
            inc = 0.5;      
            elseif strcmp(Step, '200 µm')
            inc = 0.2;           
            elseif strcmp(Step, '100 µm')
            inc = 0.1;    
            elseif strcmp(Step, '50 µm')
            inc = 0.05;    
            elseif strcmp(Step, '30 µm')
            inc = 0.03;    
            elseif strcmp(Step, '10 µm')
            inc = 0.01;   
            elseif strcmp(Step, '1 µm')
            inc = 0.001;    
            end
            
               
            SY.deviceNET.SetJogStepSize(inc); 

            Fmotordirection = Thorlabs.MotionControl.GenericMotorCLI.MotorDirection.Forward;
           
           timeout_val = 60000;
           SY.deviceNET.MoveJog(Fmotordirection, timeout_val);
         
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
           
            Step = app.StepDropDown_2.Value;
            
            SY = getappdata(0,'SY');
           
              
             if strcmp(Step, '1 mm')
            inc = 1;  
            elseif strcmp(Step, '500 µm')
            inc = 0.5;      
            elseif strcmp(Step, '200 µm')
            inc = 0.2;           
            elseif strcmp(Step, '100 µm')
            inc = 0.1;    
            elseif strcmp(Step, '50 µm')
            inc = 0.05;    
            elseif strcmp(Step, '30 µm')
            inc = 0.03;    
            elseif strcmp(Step, '10 µm')
            inc = 0.01;   
            elseif strcmp(Step, '1 µm')
            inc = 0.001;    
            end
               
            SY.deviceNET.SetJogStepSize(inc); 

           Bmotordirection = Thorlabs.MotionControl.GenericMotorCLI.MotorDirection.Backward;
           
           timeout_val = 60000;
           SY.deviceNET.MoveJog(Bmotordirection, timeout_val);
        end

        % Button pushed function: SetTopZButton
        function SetTopZButtonPushed(app, event)
          SZ =  getappdata(0, 'SZ');


          app.TopZEditField.Value = SZ.pos; 


          app.ZDistancemEditField.Value = (app.BottomZEditField.Value - app.TopZEditField.Value)*1000 ;

          app.CalculatedFramesEditField.Value = ceil(app.ZDistancemEditField.Value / app.ZStepmEditField.Value);

          app.FramesEditField.Value = app.CalculatedFramesEditField.Value;
        end

        % Button pushed function: SetBottomZButton
        function SetBottomZButtonPushed(app, event)
             SZ = getappdata(0, 'SZ');


              app.BottomZEditField.Value = SZ.pos; 


              app.ZDistancemEditField.Value = (app.BottomZEditField.Value - app.TopZEditField.Value)*1000 ;
        end

        % Callback function
        function SetButtonPushed(app, event)
            setappdata(0, 'LSPhase12', app.P3OffsetVEditField.Value);
            setappdata(0, 'LSPhase13', app.P2OffsetVEditField.Value);
        end

        % Button pushed function: GetButton_2
        function GetButton_2Pushed(app, event)
            %Get Home Pos
            
            ELLO = getappdata(0, 'ELLO');
            HomePosHex = query(ELLO, '0go');

            MinStep = 360/143360;
            
            tempvar = (HomePosHex(4:13));
            HomeOffset = round(hex2dec(tempvar)*MinStep, 4);
            clear tempvar

            app.HomeOffsetEditField.Value = HomeOffset;

            GetPosHex = query(ELLO,'0gp');
            
            
            tempvar = (GetPosHex(4:13));
            ActPos = round(hex2dec(tempvar)*MinStep, 4);
            clear tempvar    

            app.ActPosEditField.Value = ActPos;
        end

        % Button pushed function: GetButton
        function GetButtonPushed(app, event)
           
            
            ELLO = getappdata(0, 'ELLO');
            % 143360 counts / rev 
            MinStep = 360/143360;

      
            tempvar = query(ELLO,'0gj');
            tempvar = (tempvar(4:13));
            GetJogStepSize = round(hex2dec(tempvar)*MinStep, 4);
            clear tempvar

            app.JogStepEditField.Value = GetJogStepSize;
            
        end

        % Button pushed function: SetButton
        function SetButtonPushed2(app, event)
           
            % 143360 counts / rev 
            MinStep = 360/143360;
            ELLO = getappdata(0, 'ELLO');
            
            JogSize = app.JogStepEditField.Value;   % in degrees
         
            if JogSize <= 690
            tempvar = round(JogSize/MinStep, 0);
            tempvar = dec2hex(tempvar, 4);
            tempvar = append('0sj0000', num2str(tempvar));  %makes variable to set jogstepsize to specific amount
            else
            disp('JogSize too big..');
            end

            query(ELLO,tempvar); % the hexadezimal number corresponds to 23893 device units (1 DU = 0.0251119°);
            %specced in Thorlabs manual: 143360 counts / rev --> 0.00251116° / count

            clear tempvar


            setappdata(0, 'ELLO', ELLO);
        end

        % Button pushed function: GetDeviceDataButton
        function GetDeviceDataButtonPushed(app, event)
            
            ELLO = getappdata(0, 'ELLO');
            MinStep = 360/143360;

            %get device data 
            motorinfo1 = query(ELLO, '0i1');
            motorinfo2 = query(ELLO, '0i2');

            %Frequencies of motor 1
            app.Motor1FWFreqkHzEditField.Value = round(14740000/hex2dec(motorinfo1(18:21)), 0)/1000;
            app.Motor1BWFreqkHzEditField.Value = round(14740000/hex2dec(motorinfo1(22:25)), 0)/1000;

            %Frequencies of motor 2
            app.Motor2FWFreqkHzEditField.Value = round(14740000/hex2dec(motorinfo2(18:21)), 0)/1000;
            app.Motor2BWFreqkHzEditField.Value = round(14740000/hex2dec(motorinfo2(22:25)), 0)/1000;

            GetPosHex = query(ELLO,'0gp');
            
            
            tempvar = (GetPosHex(4:13));
            ActPos = round(hex2dec(tempvar)*MinStep, 4);
            clear tempvar    

            app.ActPosEditField.Value = ActPos;
           
        end

        % Button pushed function: JogBackwardButton
        function JogBackwardButtonPushed(app, event)
            ELLO = getappdata(0, 'ELLO');
            MinStep = 360/143360;

            query(ELLO, '0bw');

            GetPosHex = query(ELLO,'0gp');
            
            
            tempvar = (GetPosHex(4:13));
            ActPos = round(hex2dec(tempvar)*MinStep, 4);
            clear tempvar    

            app.ActPosEditField.Value = ActPos;


        end

        % Button pushed function: JogForwardButton
        function JogForwardButtonPushed(app, event)
            ELLO = getappdata(0, 'ELLO');
            MinStep = 360/143360;

            query(ELLO, '0fw');


            GetPosHex = query(ELLO,'0gp');
            
            
            tempvar = (GetPosHex(4:13));
            ActPos = round(hex2dec(tempvar)*MinStep, 4);
            clear tempvar    

            app.ActPosEditField.Value = ActPos;
        end

        % Button pushed function: HomeELLOButton
        function HomeELLOButtonPushed(app, event)
            ELLO = getappdata(0, 'ELLO');
            MinStep = 360/143360;
            
            query(ELLO, '0ho1');


            GetPosHex = query(ELLO,'0gp');
            
            
            tempvar = (GetPosHex(4:13));
            ActPos = round(hex2dec(tempvar)*MinStep, 4);
            clear tempvar    

            app.ActPosEditField.Value = ActPos;
        end

        % Button pushed function: FindMotorFreq1Button
        function FindMotorFreq1ButtonPushed(app, event)
            ELLO = getappdata(0, 'ELLO');
            
            
            query(ELLO, '0s1');
            

            %get device data 
            motorinfo1 = query(ELLO, '0i1');

            %Frequencies of motor 1
            app.Motor1FWFreqkHzEditField.Value = round(14740000/hex2dec(motorinfo1(18:21)), 0)/1000;
            app.Motor1BWFreqkHzEditField.Value = round(14740000/hex2dec(motorinfo1(22:25)), 0)/1000;


            setappdata(0, 'ELLO', ELLO);

        end

        % Button pushed function: FindMotorFreq2Button
        function FindMotorFreq2ButtonPushed(app, event)
            ELLO = getappdata(0, 'ELLO');
            
            
            query(ELLO, '0s2');


            motorinfo2 = query(ELLO, '0i2');

            %Frequencies of motor 2
            app.Motor2FWFreqkHzEditField.Value = round(14740000/hex2dec(motorinfo2(18:21)), 0)/1000;
            app.Motor2BWFreqkHzEditField.Value = round(14740000/hex2dec(motorinfo2(22:25)), 0)/1000;


            setappdata(0, 'ELLO', ELLO);

        end

        % Button pushed function: RecordangleLSButton
        function RecordangleLSButtonPushed(app, event)
         dqScanner = getappdata(0, 'dqScanner'); %%%%%%%%%%%%%%%%
         LineTime = getappdata(0, 'LineTime');
         TAcqTime = app.TotalAcqTimeEditField.Value; %additional time needs to be added because of the added zero arrays for sync (default +10 ms)  
         dqScanner.Channels(1, 3).Frequency = 1/(TAcqTime/1000);
         SVoltArray = getappdata(0, 'ScanArrayFull');
         MinScanTime = getappdata(0, 'MinScanTime');
         SystemDelayTime = 4.1; % System Delay Time between Rotation trigger and Image Rotation [ms] 1.32

         if length(dqScanner.Channels) < 4
         ch4 = addoutput(dqScanner, "Dev2", "ctr2", "PulseGeneration"); %PFI14
         end

         dqScanner.Channels(1, 4).Frequency = 1/((str2double(app.PhasesDropDown.Value))*TAcqTime/1000);
         dqScanner.Channels(1, 4).DutyCycle = 0.5;
         dqScanner.Channels(1, 4).InitialDelay = ((str2double(app.PhasesDropDown.Value))+1)*TAcqTime/1000-SystemDelayTime;
         
         WaitScan = TAcqTime/1000*((str2double(app.PhasesDropDown.Value))*3+2);

            if LineTime < MinScanTime
            disp('ERROR: Line integration time is too short. Increase line time..')
            else


            while length(SVoltArray) < 5000  %If Sample count is under 5k, DAQ card gives an error. Array is repeated if element count is too low..
            if length(SVoltArray) < 5000
            SVoltArray = repmat(SVoltArray,2,1);             
            end
            end
            
            preload(dqScanner, SVoltArray);  %%%%%%%%%%%%%%%%%%%
            start(dqScanner); %%%%%%%%%%%%%%%%%%%% 
            
            pause(WaitScan); 
            stop(dqScanner);
            write(dqScanner, [-4, 4]); % Send values only to analog channels
           
            removechannel(dqScanner, 4);
            flush(dqScanner);
             

            ELLO = getappdata(0, 'ELLO');
            MinStep = 360/143360;
            
            query(ELLO, '0ho1');


            GetPosHex = query(ELLO,'0gp');
            
            
            tempvar = (GetPosHex(4:13));
            ActPos = round(hex2dec(tempvar)*MinStep, 4);
            clear tempvar    

            app.ActPosEditField.Value = ActPos;
              
            disp('5 phase shifted LiL-SIMages at 3 orientation angles recorded! :)')
            end
        end

        % Button pushed function: RecordFULLSIMAGEButton
        function RecordFULLSIMAGEButtonPushed(app, event)
           
         NumFrames = app.FramenumberEditField.Value;
            
         SZ = getappdata(0, 'SZ');
         SZ.pos

         dqScanner = getappdata(0, 'dqScanner'); %%%%%%%%%%%%%%%%
         LineTime = getappdata(0, 'LineTime');
         TAcqTime = app.TotalAcqTimeEditField.Value; %additional time needs to be added because of the added zero arrays for sync (default +10 ms)  
         dqScanner.Channels(1, 3).Frequency = 1/(TAcqTime/1000);
         ScanArrayFast1F = getappdata(0, 'ScanArrayFast');
         MinScanTime = getappdata(0, 'MinScanTime');
         LSScanArray1Dir = getappdata(0, 'LSScanArray1Dir');
          
         SVoltArray = LSScanArray1Dir;
  
         if NumFrames > 1
         for i=1:NumFrames-1
         ScanArrayFast(:,:,i) = ScanArrayFast1F; %1 Frame
         end

         for i=1:NumFrames-1
         SVoltArray =  cat(1, SVoltArray, ScanArrayFast(:,:,i));
         end
         else
         i = 1;
         end
       
         WaitScan = i*TAcqTime/1000*(str2double(app.PhasesDropDown.Value)+1);

            if LineTime < MinScanTime
            disp('ERROR: Line integration time is too short. Increase line time..')
            else
    

            while length(SVoltArray) < 5000  %If Sample count is under 5k, DAQ card gives an error. Array is repeated if element count is too low..
            if length(SVoltArray) < 5000
            SVoltArray = repmat(SVoltArray,2,1);             
            end
            end
            
            preload(dqScanner, SVoltArray);  %%%%%%%%%%%%%%%%%%%
            start(dqScanner); %%%%%%%%%%%%%%%%%%%% 
            
            pause(WaitScan); 
            
            stop(dqScanner);

            stop(dqScanner);
       
            dqScanner.write([-4,-4]) 

            flush(dqScanner);

                       
            disp('LS-SIMages recorded! :)')
            end

            SZ.pos


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ImageparametersLabel = uilabel(app.UIFigure);
            app.ImageparametersLabel.Text = 'Image parameters';
            app.ObjectiveDropDownLabel = uilabel(app.UIFigure);
            app.ObjectiveDropDownLabel.Text = 'Objective';
            app.ObjectiveDropDown = uidropdown(app.UIFigure);
            app.ObjectiveDropDown.Items = {'20x', '40x', '60x', '100x'};
            app.ObjectiveDropDown.ValueChangedFcn = createCallbackFcn(app, @ObjectiveSelect, true);
            app.ObjectiveDropDown.Value = '100x';
            app.ExposuretimemsEditFieldLabel = uilabel(app.UIFigure);
            app.ExposuretimemsEditFieldLabel.Text = 'Exposure time [ms]';
            app.ExposuretimemsEditField = uieditfield(app.UIFigure, 'numeric');
            app.ExposuretimemsEditField.ValueChangedFcn = createCallbackFcn(app, @ExposuretimemsEditFieldValueChanged, true);
            app.ExposuretimemsEditField.Value = 10;
            app.ScanStepYnmEditFieldLabel = uilabel(app.UIFigure);
            app.ScanStepYnmEditFieldLabel.Text = 'Scan Step Y [nm]';
            app.ScanStepYnmEditField = uieditfield(app.UIFigure, 'numeric');
            app.ScanStepYnmEditField.ValueChangedFcn = createCallbackFcn(app, @ScanStepYnmEditFieldValueChanged, true);
            app.ScanStepYnmEditField.Value = 400;
            app.AcqTimemsEditFieldLabel = uilabel(app.UIFigure);
            app.AcqTimemsEditFieldLabel.Text = 'AcqTime [ms]';
            app.AcqTimemsEditField = uieditfield(app.UIFigure, 'numeric');
            app.AcqTimemsEditField.Editable = 'off';
            app.FOVDropDownLabel = uilabel(app.UIFigure);
            app.FOVDropDownLabel.Text = 'FOV';
            app.FOVDropDown = uidropdown(app.UIFigure);
            app.FOVDropDown.Items = {'128P', '256P', '512P', '1024P', '2048P'};
            app.FOVDropDown.ValueChangedFcn = createCallbackFcn(app, @FOVDropDownValueChanged, true);
            app.FOVDropDown.Value = '1024P';
            app.FOVmEditFieldLabel = uilabel(app.UIFigure);
            app.FOVmEditFieldLabel.Text = 'FOV [µm]';
            app.FOVmEditField = uieditfield(app.UIFigure, 'numeric');
            app.FOVmEditField.Editable = 'off';
            app.StartScanYEditField_2Label = uilabel(app.UIFigure);
            app.StartScanYEditField_2Label.Text = 'StartScanY';
            app.StartScanYEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.LoadScanPosButton = uibutton(app.UIFigure, 'push');
            app.LoadScanPosButton.ButtonPushedFcn = createCallbackFcn(app, @LoadScanPosButtonPushed, true);
            app.LoadScanPosButton.Text = 'LoadScanPos';
            app.MoveStageLabel = uilabel(app.UIFigure);
            app.MoveStageLabel.Text = 'Move Stage';
            app.StartScanXEditFieldLabel = uilabel(app.UIFigure);
            app.StartScanXEditFieldLabel.Text = 'StartScanX';
            app.StartScanXEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZStacksLabel = uilabel(app.UIFigure);
            app.ZStacksLabel.Text = 'Z-Stacks';
            app.StepDropDownLabel = uilabel(app.UIFigure);
            app.StepDropDownLabel.Text = 'Step';
            app.StepDropDown = uidropdown(app.UIFigure);
            app.StepDropDown.Items = {'1 mm', '100 µm', '50 µm', '10 µm', '1 µm', '200 nm'};
            app.StepDropDown.ValueChangedFcn = createCallbackFcn(app, @StepDropDownValueChanged, true);
            app.StepDropDown.Value = '1 µm';
            app.PlusButton = uibutton(app.UIFigure, 'push');
            app.PlusButton.ButtonPushedFcn = createCallbackFcn(app, @PlusButtonPushed, true);
            app.PlusButton.Text = '+';
            app.MinusButton = uibutton(app.UIFigure, 'push');
            app.MinusButton.ButtonPushedFcn = createCallbackFcn(app, @MinusButtonPushed, true);
            app.MinusButton.Text = '-';
            app.HomeButton = uibutton(app.UIFigure, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton.Text = 'Home';
            app.ZStepmEditFieldLabel = uilabel(app.UIFigure);
            app.ZStepmEditFieldLabel.Text = 'Z-Step [µm]';
            app.ZStepmEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZStepmEditField.ValueChangedFcn = createCallbackFcn(app, @ZStepmEditFieldValueChanged, true);
            app.SIMLabel = uilabel(app.UIFigure);
            app.SIMLabel.Text = 'SIM';
            app.FramesEditFieldLabel = uilabel(app.UIFigure);
            app.FramesEditFieldLabel.Text = 'Frames';
            app.FramesEditField = uieditfield(app.UIFigure, 'numeric');
            app.FramesEditField.ValueChangedFcn = createCallbackFcn(app, @FramesEditFieldValueChanged, true);
            app.PhasesDropDownLabel = uilabel(app.UIFigure);
            app.PhasesDropDownLabel.Text = 'Phases';
            app.PhasesDropDown = uidropdown(app.UIFigure);
            app.PhasesDropDown.Items = {'3', '5'};
            app.PhasesDropDown.ValueChangedFcn = createCallbackFcn(app, @PhasesDropDownValueChanged, true);
            app.PhasesDropDown.Value = '5';
            app.Angle1Button = uibutton(app.UIFigure, 'state');
            app.Angle1Button.ValueChangedFcn = createCallbackFcn(app, @Angle1ButtonValueChanged, true);
            app.Angle1Button.Text = 'Angle 1';
            app.Angle1Button.Value = true;
            app.Angle2Button = uibutton(app.UIFigure, 'state');
            app.Angle2Button.ValueChangedFcn = createCallbackFcn(app, @Angle2ButtonValueChanged, true);
            app.Angle2Button.Text = 'Angle 2';
            app.Angle3Button = uibutton(app.UIFigure, 'state');
            app.Angle3Button.ValueChangedFcn = createCallbackFcn(app, @Angle3ButtonValueChanged, true);
            app.Angle3Button.Text = 'Angle 3';
            app.LoadparamsButton = uibutton(app.UIFigure, 'push');
            app.LoadparamsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadparamsButtonPushed, true);
            app.LoadparamsButton.Text = 'Load params';
            app.LiveButton = uibutton(app.UIFigure, 'state');
            app.LiveButton.ValueChangedFcn = createCallbackFcn(app, @LiveButtonValueChanged, true);
            app.LiveButton.Text = 'Live';
            app.RecordImageButton = uibutton(app.UIFigure, 'push');
            app.RecordImageButton.Text = 'Record Image';
            app.RecordZStackButton = uibutton(app.UIFigure, 'push');
            app.RecordZStackButton.ButtonPushedFcn = createCallbackFcn(app, @RecordZStackButtonPushed, true);
            app.RecordZStackButton.Text = 'Record Z-Stack';
            app.RecordSIMageButton = uibutton(app.UIFigure, 'push');
            app.RecordSIMageButton.ButtonPushedFcn = createCallbackFcn(app, @RecordSIMageButtonPushed, true);
            app.RecordSIMageButton.Text = 'Record SIMage';
            app.RecordLSSIMageButton = uibutton(app.UIFigure, 'push');
            app.RecordLSSIMageButton.ButtonPushedFcn = createCallbackFcn(app, @RecordLSSIMageButtonPushed, true);
            app.RecordLSSIMageButton.Text = 'Record LS-SIMage';
            app.LightsheetModeCheckBox = uicheckbox(app.UIFigure);
            app.LightsheetModeCheckBox.Text = 'Lightsheet Mode';
            app.LightsheetModeCheckBox.Value = true;
            app.ExposurebandwidthEditFieldLabel = uilabel(app.UIFigure);
            app.ExposurebandwidthEditFieldLabel.Text = 'Exposure band width';
            app.ExposurebandwidthEditField = uieditfield(app.UIFigure, 'numeric');
            app.ExposurebandwidthEditField.Editable = 'off';
            app.LSExposureTimeLabel = uilabel(app.UIFigure);
            app.LSExposureTimeLabel.Text = 'Line Time [ns]';
            app.LineTimensEditField = uieditfield(app.UIFigure, 'numeric');
            app.LineTimensEditField.ValueDisplayFormat = '%9.0f';
            app.LineTimensEditField.Editable = 'off';
            app.TotalAcqTimeEditFieldLabel = uilabel(app.UIFigure);
            app.TotalAcqTimeEditFieldLabel.Text = 'Total Acq Time';
            app.TotalAcqTimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.TotalAcqTimeEditField.Editable = 'off';
            app.PhaseOffsetnmEditFieldLabel = uilabel(app.UIFigure);
            app.PhaseOffsetnmEditFieldLabel.Text = 'Phase Offset [nm]';
            app.PhaseOffsetnmEditField = uieditfield(app.UIFigure, 'numeric');
            app.PhaseOffsetnmEditField.Editable = 'off';
            app.includingflybacktimesLabel = uilabel(app.UIFigure);
            app.includingflybacktimesLabel.Text = 'including flyback times';
            app.CenterPosButton = uibutton(app.UIFigure, 'push');
            app.CenterPosButton.ButtonPushedFcn = createCallbackFcn(app, @CenterPosButtonPushed, true);
            app.CenterPosButton.Text = 'CenterPos';
            app.dirCheckBox = uicheckbox(app.UIFigure);
            app.dirCheckBox.Text = '1 dir';
            app.dirCheckBox.Value = true;
            app.CalcCorrOffsetYEditFieldLabel = uilabel(app.UIFigure);
            app.CalcCorrOffsetYEditFieldLabel.Text = 'Calc Corr Offset Y';
            app.CalcCorrOffsetYEditField = uieditfield(app.UIFigure, 'numeric');
            app.CalcCorrOffsetYEditField.Editable = 'off';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CalibrateLightsheetScanningTab = uitab(app.TabGroup);
            app.CalibrateLightsheetScanningTab.Title = 'Calibrate Lightsheet-Scanning';
            app.LoadLSScanPosButton = uibutton(app.CalibrateLightsheetScanningTab, 'push');
            app.LoadLSScanPosButton.ButtonPushedFcn = createCallbackFcn(app, @LoadLSScanPosButtonPushed, true);
            app.LoadLSScanPosButton.Text = 'LoadLS ScanPos';
            app.LoadLastLSScanPosButton = uibutton(app.CalibrateLightsheetScanningTab, 'push');
            app.LoadLastLSScanPosButton.ButtonPushedFcn = createCallbackFcn(app, @LoadLastLSScanPosButtonPushed, true);
            app.LoadLastLSScanPosButton.Text = 'LoadLastLS ScanPos';
            app.EnterTopLSPosEditFieldLabel = uilabel(app.CalibrateLightsheetScanningTab);
            app.EnterTopLSPosEditFieldLabel.Text = 'Enter Top LS Pos';
            app.EnterTopLSPosEditField = uieditfield(app.CalibrateLightsheetScanningTab, 'numeric');
            app.EnterTopLSPosEditField.Value = -1.912;
            app.EnterBotLSPosEditFieldLabel = uilabel(app.CalibrateLightsheetScanningTab);
            app.EnterBotLSPosEditFieldLabel.Text = 'Enter Bot LS Pos';
            app.EnterBotLSPosEditField = uieditfield(app.CalibrateLightsheetScanningTab, 'numeric');
            app.EnterBotLSPosEditField.Value = 1.108;
            app.FOVFactorEditFieldLabel = uilabel(app.CalibrateLightsheetScanningTab);
            app.FOVFactorEditFieldLabel.Text = 'FOV Factor';
            app.FOVFactorEditField = uieditfield(app.CalibrateLightsheetScanningTab, 'numeric');
            app.CalculateButton = uibutton(app.CalibrateLightsheetScanningTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.SetFOVFactorButton = uibutton(app.CalibrateLightsheetScanningTab, 'push');
            app.SetFOVFactorButton.ButtonPushedFcn = createCallbackFcn(app, @SetFOVFactorButtonPushed, true);
            app.SetFOVFactorButton.Text = 'Set FOV Factor';
            app.ResetFOVFactorButton = uibutton(app.CalibrateLightsheetScanningTab, 'push');
            app.ResetFOVFactorButton.ButtonPushedFcn = createCallbackFcn(app, @ResetFOVFactorButtonPushed, true);
            app.ResetFOVFactorButton.Text = 'Reset FOV Factor';
            app.CalibratedFOVmEditFieldLabel = uilabel(app.CalibrateLightsheetScanningTab);
            app.CalibratedFOVmEditFieldLabel.Text = 'Calibrated FOV [µm]';
            app.CalibratedFOVmEditField = uieditfield(app.CalibrateLightsheetScanningTab, 'numeric');
            app.LSCorrOffsetYEditFieldLabel = uilabel(app.CalibrateLightsheetScanningTab);
            app.LSCorrOffsetYEditFieldLabel.Text = 'LS Corr Offset Y';
            app.LSCorrOffsetYEditField = uieditfield(app.CalibrateLightsheetScanningTab, 'numeric');
            app.LSGlobalYnmEditFieldLabel = uilabel(app.CalibrateLightsheetScanningTab);
            app.LSGlobalYnmEditFieldLabel.Text = 'LS-Global-Y [nm]';
            app.LSGlobalYnmEditField = uieditfield(app.CalibrateLightsheetScanningTab, 'numeric');
            app.LSPhaseYnmEditFieldLabel = uilabel(app.CalibrateLightsheetScanningTab);
            app.LSPhaseYnmEditFieldLabel.Text = 'LS-Phase--Y [nm]';
            app.LSPhaseYnmEditField = uieditfield(app.CalibrateLightsheetScanningTab, 'numeric');
            app.finetuningofgloballightsheetoffsetLabel = uilabel(app.CalibrateLightsheetScanningTab);
            app.finetuningofgloballightsheetoffsetLabel.Text = 'fine tuning of global lightsheet offset';
            app.finetuningofdistancebetweenilluphasesLabel = uilabel(app.CalibrateLightsheetScanningTab);
            app.finetuningofdistancebetweenilluphasesLabel.Text = 'fine tuning of distance between illu phases';
            app.V22nmLabel = uilabel(app.CalibrateLightsheetScanningTab);
            app.V22nmLabel.Text = '0.001 V = 22 nm ';
            app.P2OffsetVEditFieldLabel = uilabel(app.CalibrateLightsheetScanningTab);
            app.P2OffsetVEditFieldLabel.Text = 'P2 Offset  [V]';
            app.P2OffsetVEditField = uieditfield(app.CalibrateLightsheetScanningTab, 'numeric');
            app.P2OffsetVEditField.Value = -1.912;
            app.P3OffsetVEditFieldLabel = uilabel(app.CalibrateLightsheetScanningTab);
            app.P3OffsetVEditFieldLabel.Text = 'P3 Offset [V] ';
            app.P3OffsetVEditField = uieditfield(app.CalibrateLightsheetScanningTab, 'numeric');
            app.P3OffsetVEditField.Value = -1.912;
            app.MoveStageTab = uitab(app.TabGroup);
            app.MoveStageTab.Title = 'Move Stage';
            app.Button_3 = uibutton(app.MoveStageTab, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '-';
            app.Button_4 = uibutton(app.MoveStageTab, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '+';
            app.StepDropDown_2Label = uilabel(app.MoveStageTab);
            app.StepDropDown_2Label.Text = 'Step';
            app.StepDropDown_2 = uidropdown(app.MoveStageTab);
            app.StepDropDown_2.Items = {'1 mm', '500 µm', '200 µm', '100 µm', '50 µm', '30 µm', '10 µm', '1 µm'};
            app.StepDropDown_2.Value = '1 µm';
            app.Button_7 = uibutton(app.MoveStageTab, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '-';
            app.Button_8 = uibutton(app.MoveStageTab, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_8.Text = '+';
            app.StepDropDown_2Label_2 = uilabel(app.MoveStageTab);
            app.StepDropDown_2Label_2.Text = 'Y';
            app.StepDropDown_2Label_3 = uilabel(app.MoveStageTab);
            app.StepDropDown_2Label_3.Text = 'X';
            app.SetBottomZButton = uibutton(app.MoveStageTab, 'push');
            app.SetBottomZButton.ButtonPushedFcn = createCallbackFcn(app, @SetBottomZButtonPushed, true);
            app.SetBottomZButton.Text = 'Set Bottom Z';
            app.SetTopZButton = uibutton(app.MoveStageTab, 'push');
            app.SetTopZButton.ButtonPushedFcn = createCallbackFcn(app, @SetTopZButtonPushed, true);
            app.SetTopZButton.Text = 'Set Top Z';
            app.TopZEditFieldLabel = uilabel(app.MoveStageTab);
            app.TopZEditFieldLabel.Text = 'Top Z';
            app.TopZEditField = uieditfield(app.MoveStageTab, 'numeric');
            app.TopZEditField.Editable = 'off';
            app.BottomZEditFieldLabel = uilabel(app.MoveStageTab);
            app.BottomZEditFieldLabel.Text = 'Bottom Z';
            app.BottomZEditField = uieditfield(app.MoveStageTab, 'numeric');
            app.BottomZEditField.Editable = 'off';
            app.ZStackCalculatorLabel = uilabel(app.MoveStageTab);
            app.ZStackCalculatorLabel.Text = 'Z-Stack Calculator';
            app.ZDistancemEditFieldLabel = uilabel(app.MoveStageTab);
            app.ZDistancemEditFieldLabel.Text = 'Z Distance  [µm]';
            app.ZDistancemEditField = uieditfield(app.MoveStageTab, 'numeric');
            app.ZDistancemEditField.Editable = 'off';
            app.CalculatedFramesEditFieldLabel = uilabel(app.MoveStageTab);
            app.CalculatedFramesEditFieldLabel.Text = 'Calculated Frames';
            app.CalculatedFramesEditField = uieditfield(app.MoveStageTab, 'numeric');
            app.CalculatedFramesEditField.Editable = 'off';
            app.RotationStageCalibTab = uitab(app.TabGroup);
            app.RotationStageCalibTab.Title = 'Rotation Stage Calib';
            app.HomeOffsetEditFieldLabel = uilabel(app.RotationStageCalibTab);
            app.HomeOffsetEditFieldLabel.Text = 'HomeOffset';
            app.HomeOffsetEditField = uieditfield(app.RotationStageCalibTab, 'numeric');
            app.JogStepEditFieldLabel = uilabel(app.RotationStageCalibTab);
            app.JogStepEditFieldLabel.Text = 'JogStep';
            app.JogStepEditField = uieditfield(app.RotationStageCalibTab, 'numeric');
            app.GetButton = uibutton(app.RotationStageCalibTab, 'push');
            app.GetButton.ButtonPushedFcn = createCallbackFcn(app, @GetButtonPushed, true);
            app.GetButton.Text = 'Get';
            app.SetButton = uibutton(app.RotationStageCalibTab, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed2, true);
            app.SetButton.Text = 'Set';
            app.GetButton_2 = uibutton(app.RotationStageCalibTab, 'push');
            app.GetButton_2.ButtonPushedFcn = createCallbackFcn(app, @GetButton_2Pushed, true);
            app.GetButton_2.Text = 'Get';
            app.JogBackwardButton = uibutton(app.RotationStageCalibTab, 'push');
            app.JogBackwardButton.ButtonPushedFcn = createCallbackFcn(app, @JogBackwardButtonPushed, true);
            app.JogBackwardButton.Text = 'Jog Backward';
            app.JogForwardButton = uibutton(app.RotationStageCalibTab, 'push');
            app.JogForwardButton.ButtonPushedFcn = createCallbackFcn(app, @JogForwardButtonPushed, true);
            app.JogForwardButton.Text = 'Jog Forward';
            app.HomeELLOButton = uibutton(app.RotationStageCalibTab, 'push');
            app.HomeELLOButton.ButtonPushedFcn = createCallbackFcn(app, @HomeELLOButtonPushed, true);
            app.HomeELLOButton.Text = 'Home ELLO';
            app.FindMotorFreq1Button = uibutton(app.RotationStageCalibTab, 'push');
            app.FindMotorFreq1Button.ButtonPushedFcn = createCallbackFcn(app, @FindMotorFreq1ButtonPushed, true);
            app.FindMotorFreq1Button.Text = 'Find Motor Freq 1';
            app.FindMotorFreq2Button = uibutton(app.RotationStageCalibTab, 'push');
            app.FindMotorFreq2Button.ButtonPushedFcn = createCallbackFcn(app, @FindMotorFreq2ButtonPushed, true);
            app.FindMotorFreq2Button.Text = 'Find Motor Freq 2';
            app.Motor1FWFreqkHzEditFieldLabel = uilabel(app.RotationStageCalibTab);
            app.Motor1FWFreqkHzEditFieldLabel.Text = 'Motor 1 - FW Freq [kHz]';
            app.Motor1FWFreqkHzEditField = uieditfield(app.RotationStageCalibTab, 'numeric');
            app.Motor1BWFreqkHzEditFieldLabel = uilabel(app.RotationStageCalibTab);
            app.Motor1BWFreqkHzEditFieldLabel.Text = 'Motor 1 - BW Freq [kHz]';
            app.Motor1BWFreqkHzEditField = uieditfield(app.RotationStageCalibTab, 'numeric');
            app.Motor2FWFreqkHzEditFieldLabel = uilabel(app.RotationStageCalibTab);
            app.Motor2FWFreqkHzEditFieldLabel.Text = 'Motor 2 - FW Freq [kHz]';
            app.Motor2FWFreqkHzEditField = uieditfield(app.RotationStageCalibTab, 'numeric');
            app.Motor2BWFreqkHzEditFieldLabel = uilabel(app.RotationStageCalibTab);
            app.Motor2BWFreqkHzEditFieldLabel.Text = 'Motor 2 - BW Freq [kHz]';
            app.Motor2BWFreqkHzEditField = uieditfield(app.RotationStageCalibTab, 'numeric');
            app.GetDeviceDataButton = uibutton(app.RotationStageCalibTab, 'push');
            app.GetDeviceDataButton.ButtonPushedFcn = createCallbackFcn(app, @GetDeviceDataButtonPushed, true);
            app.GetDeviceDataButton.Text = 'Get Device Data';
            app.ActPosEditFieldLabel = uilabel(app.RotationStageCalibTab);
            app.ActPosEditFieldLabel.Text = 'ActPos';
            app.ActPosEditField = uieditfield(app.RotationStageCalibTab, 'numeric');
            app.ActPosEditField.Editable = 'off';
            app.BrightfieldLampONButton = uibutton(app.UIFigure, 'state');
            app.BrightfieldLampONButton.ValueChangedFcn = createCallbackFcn(app, @BrightfieldLampONButtonValueChanged, true);
            app.BrightfieldLampONButton.Text = 'Brightfield Lamp ON';
            app.SlideButton = uibutton(app.UIFigure, 'push');
            app.SlideButton.ButtonPushedFcn = createCallbackFcn(app, @SlideButtonPushed, true);
            app.SlideButton.Text = 'Slide';
            app.ProgressEditFieldLabel = uilabel(app.UIFigure);
            app.ProgressEditFieldLabel.Text = 'Progress';
            app.ProgressEditField = uieditfield(app.UIFigure, 'text');
            app.RecordangleLSButton = uibutton(app.UIFigure, 'push');
            app.RecordangleLSButton.ButtonPushedFcn = createCallbackFcn(app, @RecordangleLSButtonPushed, true);
            app.RecordangleLSButton.Text = 'Record angle LS';
            app.STOPZCheckBox = uicheckbox(app.UIFigure);
            app.STOPZCheckBox.Text = 'STOPZ';
            app.RecordFULLSIMAGEButton = uibutton(app.UIFigure, 'push');
            app.RecordFULLSIMAGEButton.ButtonPushedFcn = createCallbackFcn(app, @RecordFULLSIMAGEButtonPushed, true);
            app.RecordFULLSIMAGEButton.Text = 'Record FULL SIMAGE';
            app.FramenumberEditFieldLabel = uilabel(app.UIFigure);
            app.FramenumberEditFieldLabel.Text = 'Frame number';
            app.FramenumberEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUIAPP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end